classdef RocketApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        FileMenu                     matlab.ui.container.Menu
        UnitsMenu                    matlab.ui.container.Menu
        RunMenu                      matlab.ui.container.Menu
        MainLayout                   matlab.ui.container.GridLayout
        MainTabGroup                 matlab.ui.container.TabGroup
        InputsTab                    matlab.ui.container.Tab
        InputsGridLayout             matlab.ui.container.GridLayout
        EngineDefinitionPanel        matlab.ui.container.Panel
        EngineDefinitionGridLayout   matlab.ui.container.GridLayout
        MaxThroatTemperatureEditFieldWithUnit  EditFieldWithUnit
        MaxChamberTemperatureEditFieldWithUnit  EditFieldWithUnit
        AtmosphericTemperatureEditFieldWithUnit  EditFieldWithUnit
        AtmosphericPressureEditFieldWithUnit  EditFieldWithUnit
        AtmosphereLabel              matlab.ui.control.Label
        ChamberLabel                 matlab.ui.control.Label
        InjectionLabel               matlab.ui.control.Label
        GeometryLabel                matlab.ui.control.Label
        HeatPowerEditFieldWithUnit   EditFieldWithUnit
        InjectionTemperatureEditFieldWithUnit  EditFieldWithUnit
        InjectionPressureEditFieldWithUnit  EditFieldWithUnit
        ContractionRatioEditFieldWithUnit  EditFieldWithUnit
        ExpansionRatioEditFieldWithUnit  EditFieldWithUnit
        ProblemDefinitionPanel       matlab.ui.container.Panel
        ProblemDefinitionGridLayout  matlab.ui.container.GridLayout
        ProblemsettingsLabel         matlab.ui.control.Label
        ProblemtypeButtonGroup       matlab.ui.container.ButtonGroup
        EngineAnalysisButton         matlab.ui.control.RadioButton
        FindOptimalNozzleButton      matlab.ui.control.RadioButton
        FindOptimalPropellantButton  matlab.ui.control.RadioButton
        PropellantsPanel             matlab.ui.container.Panel
        PropellantsGridLayout        matlab.ui.container.GridLayout
        EditableTable                EditableTable
        PropellantSpeciesPanel       matlab.ui.container.Panel
        PropellantSpeciesGridLayout  matlab.ui.container.GridLayout
        EditableTable2               EditableTable
        OutputsTab                   matlab.ui.container.Tab
        OutputsGridLayout            matlab.ui.container.GridLayout
    end

    properties (Access = private)
        editFieldArray % Description
        unitArray
    end
    
    methods (Access = private)
        function updateunits(app)
            import Unit.*
            
            for editField = app.editFieldArray
                editField.updateunits(app.unitArray.(editField.unitType));
            end
        end

        function EditFieldRangeSwitched(app, event)
            for editField = app.editFieldArray
                editField.disableRange;
            end
        end

        function importunits(app)
            import Unit.*

            folders = strtrim(string(ls('Units')));
            folders = ("Units\" + folders(3 : end))';
            for folder = folders
                files = strtrim(string(ls(folder)));
                files = (folder + "\" + files(3 : end))';
                i = 1;
                for file = files
                    unit = Unit(file);
                    unitTypes().tostr(unit.gettype);
                    app.unitArray.(unitTypes().tostr(unit.gettype))(i) = unit;
                    i = i + 1;
                end
            end
            updateunits(app);
        end
        
        function setupeditfields(app)
            app.editFieldArray = EditFieldWithUnit.empty;
            app.editFieldArray(end + 1) = app.ExpansionRatioEditFieldWithUnit;
            app.editFieldArray(end).updateunits(app.unitArray.(app.editFieldArray(end).unitType), 1, 4);
            app.editFieldArray(end + 1) = app.ContractionRatioEditFieldWithUnit;
            app.editFieldArray(end).updateunits(app.unitArray.(app.editFieldArray(end).unitType), 1, 4);
            app.editFieldArray(end + 1) = app.InjectionPressureEditFieldWithUnit;
            app.editFieldArray(end).updateunits(app.unitArray.(app.editFieldArray(end).unitType), 1, 2);
            app.editFieldArray(end + 1) = app.InjectionTemperatureEditFieldWithUnit;
            app.editFieldArray(end).updateunits(app.unitArray.(app.editFieldArray(end).unitType), 1, 3);
            app.editFieldArray(end + 1) = app.HeatPowerEditFieldWithUnit;
            app.editFieldArray(end).updateunits(app.unitArray.(app.editFieldArray(end).unitType), 1, 3);
            app.editFieldArray(end + 1) = app.AtmosphericPressureEditFieldWithUnit;
            app.editFieldArray(end).updateunits(app.unitArray.(app.editFieldArray(end).unitType), 1, 1);
            app.editFieldArray(end + 1) = app.AtmosphericTemperatureEditFieldWithUnit;
            app.editFieldArray(end).updateunits(app.unitArray.(app.editFieldArray(end).unitType), 1, 1);
            app.editFieldArray(end + 1) = app.MaxThroatTemperatureEditFieldWithUnit;
            app.editFieldArray(end).updateunits(app.unitArray.(app.editFieldArray(end).unitType), 1, 3);
            app.editFieldArray(end + 1) = app.MaxChamberTemperatureEditFieldWithUnit;
            app.editFieldArray(end).updateunits(app.unitArray.(app.editFieldArray(end).unitType), 1, 3);
            
            for editField = app.editFieldArray
                editField.updateunits(app.unitArray.(editField.unitType));
                editField.rangeswitchedFcn = createCallbackFcn(app, @EditFieldRangeSwitched, true);
            end

        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            importunits(app);
            setupeditfields(app);
            updateunits(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.UnitsMenu = uimenu(app.UIFigure);
            app.UnitsMenu.Text = 'Units';
            app.RunMenu = uimenu(app.UIFigure);
            app.RunMenu.Text = 'Run';
            app.MainLayout = uigridlayout(app.UIFigure);
            app.MainTabGroup = uitabgroup(app.MainLayout);
            app.InputsTab = uitab(app.MainTabGroup);
            app.InputsTab.Title = 'Inputs';
            app.InputsGridLayout = uigridlayout(app.InputsTab);
            app.PropellantSpeciesPanel = uipanel(app.InputsGridLayout);
            app.PropellantSpeciesPanel.Title = 'Selected propellant species';
            app.PropellantSpeciesGridLayout = uigridlayout(app.PropellantSpeciesPanel);
            app.EditableTable2 = EditableTable(app.PropellantSpeciesGridLayout);
            app.PropellantsPanel = uipanel(app.InputsGridLayout);
            app.PropellantsPanel.Title = 'Propellants';
            app.PropellantsGridLayout = uigridlayout(app.PropellantsPanel);
            app.EditableTable = EditableTable(app.PropellantsGridLayout);
            app.ProblemDefinitionPanel = uipanel(app.InputsGridLayout);
            app.ProblemDefinitionPanel.Title = 'Problem definition';
            app.ProblemDefinitionGridLayout = uigridlayout(app.ProblemDefinitionPanel);
            app.ProblemtypeButtonGroup = uibuttongroup(app.ProblemDefinitionGridLayout);
            app.ProblemtypeButtonGroup.Title = 'Problem type';
            app.FindOptimalPropellantButton = uiradiobutton(app.ProblemtypeButtonGroup);
            app.FindOptimalPropellantButton.Text = 'Find Optimal Propellant';
            app.FindOptimalPropellantButton.Value = true;
            app.FindOptimalNozzleButton = uiradiobutton(app.ProblemtypeButtonGroup);
            app.FindOptimalNozzleButton.Text = 'Find Optimal Nozzle';
            app.EngineAnalysisButton = uiradiobutton(app.ProblemtypeButtonGroup);
            app.EngineAnalysisButton.Text = 'Engine Analysis';
            app.ProblemsettingsLabel = uilabel(app.ProblemDefinitionGridLayout);
            app.ProblemsettingsLabel.Text = 'Problem settings';
            app.EngineDefinitionPanel = uipanel(app.InputsGridLayout);
            app.EngineDefinitionPanel.Title = 'Engine definition';
            app.EngineDefinitionGridLayout = uigridlayout(app.EngineDefinitionPanel);
            app.ExpansionRatioEditFieldWithUnit = EditFieldWithUnit(app.EngineDefinitionGridLayout);
            app.ExpansionRatioEditFieldWithUnit.name = "Expansion ratio";
            app.ExpansionRatioEditFieldWithUnit.unitType = "ratio";
            app.ExpansionRatioEditFieldWithUnit.value = [10 100];
            app.ContractionRatioEditFieldWithUnit = EditFieldWithUnit(app.EngineDefinitionGridLayout);
            app.ContractionRatioEditFieldWithUnit.name = "Contraction ratio";
            app.ContractionRatioEditFieldWithUnit.unitType = "ratio";
            app.ContractionRatioEditFieldWithUnit.value = [5 20];
            app.InjectionPressureEditFieldWithUnit = EditFieldWithUnit(app.EngineDefinitionGridLayout);
            app.InjectionPressureEditFieldWithUnit.name = "Injection Pressure";
            app.InjectionPressureEditFieldWithUnit.unitType = "pressure";
            app.InjectionPressureEditFieldWithUnit.value = [1000000 30000000];
            app.InjectionTemperatureEditFieldWithUnit = EditFieldWithUnit(app.EngineDefinitionGridLayout);
            app.InjectionTemperatureEditFieldWithUnit.name = "Injection Temperature";
            app.InjectionTemperatureEditFieldWithUnit.unitType = "temperature";
            app.InjectionTemperatureEditFieldWithUnit.value = [150 300];
            app.HeatPowerEditFieldWithUnit = EditFieldWithUnit(app.EngineDefinitionGridLayout);
            app.HeatPowerEditFieldWithUnit.name = "Heat Power";
            app.HeatPowerEditFieldWithUnit.unitType = "power";
            app.HeatPowerEditFieldWithUnit.value = [0 1000];
            app.GeometryLabel = uilabel(app.EngineDefinitionGridLayout);
            app.GeometryLabel.Text = 'Geometry';
            app.InjectionLabel = uilabel(app.EngineDefinitionGridLayout);
            app.InjectionLabel.Text = 'Injection';
            app.ChamberLabel = uilabel(app.EngineDefinitionGridLayout);
            app.ChamberLabel.Text = 'Chamber';
            app.AtmosphereLabel = uilabel(app.EngineDefinitionGridLayout);
            app.AtmosphereLabel.Text = 'Atmosphere';
            app.AtmosphericPressureEditFieldWithUnit = EditFieldWithUnit(app.EngineDefinitionGridLayout);
            app.AtmosphericPressureEditFieldWithUnit.name = "Atmospheric Pressure";
            app.AtmosphericPressureEditFieldWithUnit.unitType = "pressure";
            app.AtmosphericPressureEditFieldWithUnit.value = [101325 1.01325e-05];
            app.AtmosphericTemperatureEditFieldWithUnit = EditFieldWithUnit(app.EngineDefinitionGridLayout);
            app.AtmosphericTemperatureEditFieldWithUnit.name = "Atmospheric Temperature";
            app.AtmosphericTemperatureEditFieldWithUnit.unitType = "temperature";
            app.AtmosphericTemperatureEditFieldWithUnit.value = [273.15 303.15];
            app.MaxChamberTemperatureEditFieldWithUnit = EditFieldWithUnit(app.EngineDefinitionGridLayout);
            app.MaxChamberTemperatureEditFieldWithUnit.name = "Max Chamber Temperature";
            app.MaxChamberTemperatureEditFieldWithUnit.unitType = "temperature";
            app.MaxChamberTemperatureEditFieldWithUnit.value = [1000 4000];
            app.MaxThroatTemperatureEditFieldWithUnit = EditFieldWithUnit(app.EngineDefinitionGridLayout);
            app.MaxThroatTemperatureEditFieldWithUnit.name = "Max Throat Temperature";
            app.MaxThroatTemperatureEditFieldWithUnit.unitType = "temperature";
            app.MaxThroatTemperatureEditFieldWithUnit.value = [1000 4000];
            app.OutputsTab = uitab(app.MainTabGroup);
            app.OutputsTab.Title = 'Outputs';
            app.OutputsGridLayout = uigridlayout(app.OutputsTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RocketApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end